classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Title               matlab.ui.control.Label
        InputN              matlab.ui.control.EditField
        NhpNEditFieldLabel  matlab.ui.control.Label
        InputB              matlab.ui.control.EditField
        NhpbEditFieldLabel  matlab.ui.control.Label
        InputA              matlab.ui.control.EditField
        NhpaEditFieldLabel  matlab.ui.control.Label
        ResultText          matlab.ui.control.Label
        InputFx             matlab.ui.control.EditField
        NhpvaohamsfxLabel   matlab.ui.control.Label
        MethodOptions       matlab.ui.control.DropDown
        ChonphngphapTchphnHnhthangSimpson13Simpson38Label  matlab.ui.control.Label
        CalcButton          matlab.ui.control.Button
        Axes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IntegralObject; % Description
    end 

    
    methods (Access = private)

        function newstr = appendDot(app, source)
            newstr = strrep(source, '^', '.^');
            newstr = strrep(newstr, '*', '.*');
            newstr = strrep(newstr, '/', './');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IntegralObject = TichPhan('', '', '', '', 0);
        end

        % Value changed function: InputA
        function InputAValueChanged(app, event)
            value = str2double(app.InputA.Value);
            
            if ~isnan(value)
                app.IntegralObject.setA(value);
            else
                msgbox(["Dữ liệu a đang trống!";"Vui lòng nhập lại."],'Warning', 'warn');
                app.IntegralObject.setA('');
            end
        end

        % Value changed function: InputB
        function InputBValueChanged(app, event)
            value = str2double(app.InputB.Value);

            if ~isnan(value)
                app.IntegralObject.setB(value);
            else
                msgbox(["Dữ liệu b đang trống!";"Vui lòng nhập lại."],'Warning', 'warn');
                app.IntegralObject.setB('');
            end
        end

        % Value changed function: InputFx
        function InputFxValueChanged(app, event)
            value = app.InputFx.Value;           

            try
                newValue = app.appendDot(value);
                syms x;
                f = str2func(['@(x)', newValue]);
                app.IntegralObject.setFx(f);
            catch
                msgbox(["Nhập hàm không đúng.";"Hãy kiểm tra xem có thiếu các dấu ^, *, /, ngoặc hay không."; "Vui lòng nhập lại."], "Error", 'error');
                app.IntegralObject.setFx('');
            end
        end

        % Value changed function: InputN
        function InputNValueChanged(app, event)
            value = str2double(app.InputN.Value);
            
            if ~isnan(value)
                if value <= 0
                    cla(app.Axes);
                    msgbox(["Nhập sai dữ liệu N!"; "N phải lớn hơn 0."; "Vui lòng nhập lại"],'Error', 'error');
                    app.IntegralObject.setN('');
                else
                    app.IntegralObject.setN(value);
                end
            else
                msgbox(["Dữ liệu N đang trống!";"Vui lòng nhập lại."],'Warning', 'warn');
                app.IntegralObject.setN('');
            end
        end

        % Value changed function: MethodOptions
        function MethodOptionsValueChanged(app, event)
            value = app.MethodOptions.Value;
            
            if strcmp(value, 'Hình thang')
                app.IntegralObject.setIntegralMethod(app.IntegralObject.PP_HINH_THANG);
                app.InputN.Enable = "on";
            elseif strcmp(value, 'Simpson')
                app.IntegralObject.setIntegralMethod(app.IntegralObject.PP_SIMPSON);
                app.InputN.Enable = "on";
            else 
                app.IntegralObject.setIntegralMethod(app.IntegralObject.PP_CHINH_XAC);
                app.InputN.Enable = "off";
            end
        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            try
                a = app.IntegralObject.getA();
                b = app.IntegralObject.getB();
                
                if a > b || b < a
                    cla(app.Axes);
                    msgbox(["Nhập sai dữ liệu!"; "a và b phải thỏa a < b hoặc b > a.";"Vui lòng kiếm tra lại các dữ liệu đã nhập."],'Error', 'error');               
                else
                    x = linspace(a, b);
                    fx = app.IntegralObject.getFx();
                    plot(app.Axes, x, fx(x));

                    if app.IntegralObject.getIntegralMethod() == app.IntegralObject.PP_HINH_THANG
                        result = app.IntegralObject.tichPhanHinhThang();
                        app.ResultText.Text = sprintf("Kết quả theo phương pháp\n hình thang: %.10f", result);
                    elseif app.IntegralObject.getIntegralMethod() == app.IntegralObject.PP_SIMPSON
                        result = app.IntegralObject.tichPhanSimpson();
                        app.ResultText.Text = sprintf("Kết quả theo phương pháp\n Simpson: %.10f", result);
                    else
                        result = app.IntegralObject.tichPhanChinhXac();
                        app.ResultText.Text = sprintf("Kết quả theo phương pháp\n chính xác: %.10f", result);
                    end
                end
            catch
                cla(app.Axes);
                msgbox(["Nhập thiếu hoặc sai dữ liệu!"; "Vui lòng kiếm tra lại các dữ liệu đã nhập."],'Error', 'error');
                app.ResultText.Text = sprintf("Kết quả: ");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Hàm số f(x) trong khoảng [a, b]')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.CalcButton = uibutton(app.UIFigure, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Tính';
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label = uilabel(app.UIFigure);
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label.Text = {'Chọn phương pháp Tích phân: '; 'Hình thang, Simpson, Chính xác'};
            app.MethodOptions = uidropdown(app.UIFigure);
            app.MethodOptions.Items = {'Hình thang', 'Simpson', 'Chính xác'};
            app.MethodOptions.ValueChangedFcn = createCallbackFcn(app, @MethodOptionsValueChanged, true);
            app.MethodOptions.Value = 'Hình thang';
            app.NhpvaohamsfxLabel = uilabel(app.UIFigure);
            app.NhpvaohamsfxLabel.Text = 'Nhập vào hàm số f(x)';
            app.InputFx = uieditfield(app.UIFigure, 'text');
            app.InputFx.ValueChangedFcn = createCallbackFcn(app, @InputFxValueChanged, true);
            app.ResultText = uilabel(app.UIFigure);
            app.ResultText.Text = 'Kết quả:';
            app.NhpaEditFieldLabel = uilabel(app.UIFigure);
            app.NhpaEditFieldLabel.Text = 'Nhập a';
            app.InputA = uieditfield(app.UIFigure, 'text');
            app.InputA.ValueChangedFcn = createCallbackFcn(app, @InputAValueChanged, true);
            app.NhpbEditFieldLabel = uilabel(app.UIFigure);
            app.NhpbEditFieldLabel.Text = 'Nhập b';
            app.InputB = uieditfield(app.UIFigure, 'text');
            app.InputB.ValueChangedFcn = createCallbackFcn(app, @InputBValueChanged, true);
            app.NhpNEditFieldLabel = uilabel(app.UIFigure);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.InputN = uieditfield(app.UIFigure, 'text');
            app.InputN.ValueChangedFcn = createCallbackFcn(app, @InputNValueChanged, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Giao diện tính tích phân';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end